classdef mycalci < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalculatorUIFigure  matlab.ui.Figure
        Panel_2             matlab.ui.container.Panel
        label_result        matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
        button_result       matlab.ui.control.Button
        button_div          matlab.ui.control.Button
        button_multi        matlab.ui.control.Button
        button_minus        matlab.ui.control.Button
        button_plus         matlab.ui.control.Button
        clearButton         matlab.ui.control.Button
        digit0              matlab.ui.control.Button
        dotButton           matlab.ui.control.Button
        digit3              matlab.ui.control.Button
        digit2              matlab.ui.control.Button
        digit1              matlab.ui.control.Button
        digit6              matlab.ui.control.Button
        digit5              matlab.ui.control.Button
        digit4              matlab.ui.control.Button
        digit9              matlab.ui.control.Button
        digit8              matlab.ui.control.Button
        digit7              matlab.ui.control.Button
    end

    
    properties (Access = private)
        dotNumber; 
        enableDot = 0;
        integerNumber = 0;
        num1; % First Number
        num2; % Second Number
        sign; % + - * / 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: digit1
        function digit1ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '1'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '1'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: digit2
        function digit2ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '2'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '2'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: digit3
        function digit3ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '3'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '3'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: digit4
        function digit4ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '4'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '4'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: digit5
        function digit5ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '5'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '5'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: digit6
        function digit6ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '6'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '6'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: digit7
        function digit7ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '7'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '7'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: digit8
        function digit8ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '8'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '8'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: digit9
        function digit9ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '9'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '9'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: digit0
        function digit0ButtonPushed(app, event)
            result = '0';
            if app.enableDot == 1 % we add float number here
                app.dotNumber = [app.dotNumber '0'];
                result = [num2str(app.integerNumber) '.' app.dotNumber];
            else % Normal calculation
                result = [app.label_result.Text '0'];
            end
            newResult = str2num(result); % convert to number
            app.label_result.Text = num2str(newResult); % convert to sting for display
        end

        % Button pushed function: dotButton
        function dotButtonPushed(app, event)
           app.enableDot = 1;
           app.integerNumber = str2num(app.label_result.Text);
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            app.label_result.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0; % disable dot number
        end

        % Button pushed function: button_plus
        function button_plusButtonPushed(app, event)
            app.sign = '+';
            app.num1 = str2num(app.label_result.Text);
            app.label_result.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0;
        end

        % Button pushed function: button_result
        function button_resultButtonPushed(app, event)
            app.num2 = str2num(app.label_result.Text);

            if app.sign == '+'
                app.label_result.Text = num2str(app.num1 + app.num2);
            elseif app.sign == '-'
                app.label_result.Text = num2str(app.num1 - app.num2);
            elseif app.sign == '*'
                app.label_result.Text = num2str(app.num1 * app.num2);
            elseif app.sign == '/'
                app.label_result.Text = num2str(app.num1 / app.num2);
            end
        end

        % Button pushed function: button_minus
        function button_minusButtonPushed(app, event)
            app.sign = '-';
            app.num1 = str2num(app.label_result.Text);
            app.label_result.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0;
        end

        % Button pushed function: button_multi
        function button_multiButtonPushed(app, event)
            app.sign = '*';
            app.num1 = str2num(app.label_result.Text);
            app.label_result.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0;
        end

        % Button pushed function: button_div
        function button_divButtonPushed(app, event)
            app.sign = '/';
            app.num1 = str2num(app.label_result.Text);
            app.label_result.Text = '0';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CalculatorUIFigure = uifigure('Visible', 'off');
            app.CalculatorUIFigure.Name = 'Calculator';
            app.CalculatorUIFigure.Icon = fullfile(pathToMLAPP, 'images.png');
            app.Panel = uipanel(app.CalculatorUIFigure);
            app.digit7 = uibutton(app.Panel, 'push');
            app.digit7.ButtonPushedFcn = createCallbackFcn(app, @digit7ButtonPushed, true);
            app.digit7.Text = '7';
            app.digit8 = uibutton(app.Panel, 'push');
            app.digit8.ButtonPushedFcn = createCallbackFcn(app, @digit8ButtonPushed, true);
            app.digit8.Text = '8';
            app.digit9 = uibutton(app.Panel, 'push');
            app.digit9.ButtonPushedFcn = createCallbackFcn(app, @digit9ButtonPushed, true);
            app.digit9.Text = '9';
            app.digit4 = uibutton(app.Panel, 'push');
            app.digit4.ButtonPushedFcn = createCallbackFcn(app, @digit4ButtonPushed, true);
            app.digit4.Text = '4';
            app.digit5 = uibutton(app.Panel, 'push');
            app.digit5.ButtonPushedFcn = createCallbackFcn(app, @digit5ButtonPushed, true);
            app.digit5.Text = '5';
            app.digit6 = uibutton(app.Panel, 'push');
            app.digit6.ButtonPushedFcn = createCallbackFcn(app, @digit6ButtonPushed, true);
            app.digit6.Text = '6';
            app.digit1 = uibutton(app.Panel, 'push');
            app.digit1.ButtonPushedFcn = createCallbackFcn(app, @digit1ButtonPushed, true);
            app.digit1.Text = '1';
            app.digit2 = uibutton(app.Panel, 'push');
            app.digit2.ButtonPushedFcn = createCallbackFcn(app, @digit2ButtonPushed, true);
            app.digit2.Text = '2';
            app.digit3 = uibutton(app.Panel, 'push');
            app.digit3.ButtonPushedFcn = createCallbackFcn(app, @digit3ButtonPushed, true);
            app.digit3.Text = '3';
            app.dotButton = uibutton(app.Panel, 'push');
            app.dotButton.ButtonPushedFcn = createCallbackFcn(app, @dotButtonPushed, true);
            app.dotButton.Text = '.';
            app.digit0 = uibutton(app.Panel, 'push');
            app.digit0.ButtonPushedFcn = createCallbackFcn(app, @digit0ButtonPushed, true);
            app.digit0.Text = '0';
            app.clearButton = uibutton(app.Panel, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'C';
            app.button_plus = uibutton(app.Panel, 'push');
            app.button_plus.ButtonPushedFcn = createCallbackFcn(app, @button_plusButtonPushed, true);
            app.button_plus.Text = '+';
            app.button_minus = uibutton(app.Panel, 'push');
            app.button_minus.ButtonPushedFcn = createCallbackFcn(app, @button_minusButtonPushed, true);
            app.button_minus.Text = '-';
            app.button_multi = uibutton(app.Panel, 'push');
            app.button_multi.ButtonPushedFcn = createCallbackFcn(app, @button_multiButtonPushed, true);
            app.button_multi.Text = '*';
            app.button_div = uibutton(app.Panel, 'push');
            app.button_div.ButtonPushedFcn = createCallbackFcn(app, @button_divButtonPushed, true);
            app.button_div.Text = '/';
            app.button_result = uibutton(app.Panel, 'push');
            app.button_result.ButtonPushedFcn = createCallbackFcn(app, @button_resultButtonPushed, true);
            app.button_result.Text = '=';
            app.Panel_2 = uipanel(app.CalculatorUIFigure);
            app.label_result = uilabel(app.Panel_2);
            app.label_result.Text = '0';
            % Show the figure after all components are created
            app.CalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mycalci

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalculatorUIFigure)
        end
    end
end